classdef SubjectInfo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        subject_code_spinner        matlab.ui.control.Spinner
        SubjectcodeSpinnerLabel     matlab.ui.control.Label
        dominant_eye_button_group   matlab.ui.container.ButtonGroup
        LeftButton_4                matlab.ui.control.RadioButton
        RightButton_4               matlab.ui.control.RadioButton
        dominant_hand_button_group  matlab.ui.container.ButtonGroup
        BothButton                  matlab.ui.control.RadioButton
        LeftButton_3                matlab.ui.control.RadioButton
        RightButton_3               matlab.ui.control.RadioButton
        age_spinner                 matlab.ui.control.Spinner
        AgeSpinnerLabel             matlab.ui.control.Label
        gender_button_group         matlab.ui.container.ButtonGroup
        OtherButton                 matlab.ui.control.RadioButton
        FemaleButton                matlab.ui.control.RadioButton
        MaleButton                  matlab.ui.control.RadioButton
        CancelButton                matlab.ui.control.Button
        save_button                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        data % Structure for passing out data from the app
        save_clicked
        cancel_clicked
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.save_clicked = 0;
            app.cancel_clicked = 0;
        end

        % Button pushed function: save_button
        function save_button_pushed(app, event)
            app.data.subject_code = app.subject_code_spinner.Value;
            app.data.age = app.age_spinner.Value;
            app.data.gender = app.gender_button_group.SelectedObject.Text;
            app.data.dominant_hand = app.dominant_hand_button_group.SelectedObject.Text;
            app.data.dominant_eye = app.dominant_eye_button_group.SelectedObject.Text;
            app.save_clicked = 1;
        end

        % Button pushed function: CancelButton
        function cancel_button_pushed(app, event)
            app.cancel_clicked = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.save_button = uibutton(app.UIFigure, 'push');
            app.save_button.ButtonPushedFcn = createCallbackFcn(app, @save_button_pushed, true);
            app.save_button.Text = 'Save';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancel_button_pushed, true);
            app.CancelButton.Text = 'Cancel';
            app.gender_button_group = uibuttongroup(app.UIFigure);
            app.gender_button_group.Title = 'Gender';
            app.MaleButton = uiradiobutton(app.gender_button_group);
            app.MaleButton.Text = 'Male';
            app.MaleButton.Value = true;
            app.FemaleButton = uiradiobutton(app.gender_button_group);
            app.FemaleButton.Text = 'Female';
            app.OtherButton = uiradiobutton(app.gender_button_group);
            app.OtherButton.Text = 'Other';
            app.AgeSpinnerLabel = uilabel(app.UIFigure);
            app.AgeSpinnerLabel.Text = 'Age';
            app.age_spinner = uispinner(app.UIFigure);
            app.age_spinner.Limits = [0 1000];
            app.age_spinner.ValueDisplayFormat = '%d';
            app.age_spinner.Value = 30;
            app.dominant_hand_button_group = uibuttongroup(app.UIFigure);
            app.dominant_hand_button_group.Title = 'Dominant hand';
            app.RightButton_3 = uiradiobutton(app.dominant_hand_button_group);
            app.RightButton_3.Text = 'Right';
            app.RightButton_3.Value = true;
            app.LeftButton_3 = uiradiobutton(app.dominant_hand_button_group);
            app.LeftButton_3.Text = 'Left';
            app.BothButton = uiradiobutton(app.dominant_hand_button_group);
            app.BothButton.Text = 'Both';
            app.dominant_eye_button_group = uibuttongroup(app.UIFigure);
            app.dominant_eye_button_group.Title = 'Dominant eye';
            app.RightButton_4 = uiradiobutton(app.dominant_eye_button_group);
            app.RightButton_4.Text = 'Right';
            app.RightButton_4.Value = true;
            app.LeftButton_4 = uiradiobutton(app.dominant_eye_button_group);
            app.LeftButton_4.Text = 'Left';
            app.SubjectcodeSpinnerLabel = uilabel(app.UIFigure);
            app.SubjectcodeSpinnerLabel.Text = 'Subject code';
            app.subject_code_spinner = uispinner(app.UIFigure);
            app.subject_code_spinner.ValueDisplayFormat = '%d';
            app.subject_code_spinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SubjectInfo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end